classdef Calculating_Satellite_Position < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        v100UIFigure          matlab.ui.Figure
        yEditFieldLabel       matlab.ui.control.Label
        yEditField            matlab.ui.control.NumericEditField
        mEditFieldLabel       matlab.ui.control.Label
        mEditField            matlab.ui.control.NumericEditField
        dEditFieldLabel       matlab.ui.control.Label
        dEditField            matlab.ui.control.NumericEditField
        hEditFieldLabel       matlab.ui.control.Label
        hEditField            matlab.ui.control.NumericEditField
        mEditField_2Label     matlab.ui.control.Label
        mEditField_2          matlab.ui.control.NumericEditField
        sEditFieldLabel       matlab.ui.control.Label
        sEditField            matlab.ui.control.NumericEditField
        yEditField_2Label     matlab.ui.control.Label
        yEditField_2          matlab.ui.control.NumericEditField
        mEditField_4Label     matlab.ui.control.Label
        mEditField_4          matlab.ui.control.NumericEditField
        dEditField_2Label     matlab.ui.control.Label
        dEditField_2          matlab.ui.control.NumericEditField
        hEditField_2Label     matlab.ui.control.Label
        hEditField_2          matlab.ui.control.NumericEditField
        mEditField_3Label     matlab.ui.control.Label
        mEditField_3          matlab.ui.control.NumericEditField
        sEditField_2Label     matlab.ui.control.Label
        sEditField_2          matlab.ui.control.NumericEditField
        ObservationTimeLabel  matlab.ui.control.Label
        EphemerisTimeLabel    matlab.ui.control.Label
        pathLabel             matlab.ui.control.Label
        pathEditField         matlab.ui.control.EditField
        OutputLabel           matlab.ui.control.Label
        XsEditFieldLabel      matlab.ui.control.Label
        XsEditField           matlab.ui.control.NumericEditField
        ZsEditFieldLabel      matlab.ui.control.Label
        ZsEditField           matlab.ui.control.NumericEditField
        YsEditFieldLabel      matlab.ui.control.Label
        YsEditField           matlab.ui.control.NumericEditField
        NotesTextAreaLabel    matlab.ui.control.Label
        NotesTextArea         matlab.ui.control.TextArea
        StartButton           matlab.ui.control.Button
        PRNEditFieldLabel     matlab.ui.control.Label
        PRNEditField          matlab.ui.control.NumericEditField
        unitmLabel            matlab.ui.control.Label
        TextArea              matlab.ui.control.TextArea
    end

    methods (Access = private)
    
        function [Xs,Ys,Zs] = comsatpos(app,t,toc,a0,a1,a2,Crs,Delta_n,M0,Cuc,e,Cus,sqrtA,toe,Cic,OMEGA,Cis,i0,Crc,omega,OMEGA_DOT,iDOT)
%摄动改正后的升交距角uk、卫星矢径rk和轨道倾角ik
%卫星在轨道面坐标系中的坐标x,y
%发射时刻升交点的经度L
%卫星在地固坐标系下坐标Xs,Ys,Zs
%观测时间t，参考时刻toe，a0,a1,a2
%16参数
%Crs Delta_n M0  
%Cuc e Cus sqrtA 
%toe Cic  OMEGA Cis
%i0 Crc omega OMEGA_DOT  iDOT
%
%以下为计算代码
%1.计算卫星运行的平均角速度
n0=sqrt(3.986005E+14)/(sqrtA.^3);
n=n0+Delta_n;
%2.计算信号发射时卫星的平近点角
Deltat=a0+a1*(t-toc)+a2*(t-toc).^2;%t为未做钟差改正的观测时刻
t=t-Deltat;%更新t为做钟差改正后的值
tk=t-toe;%归化时间
% if tk>302400
%     tk=tk-604800;
% elseif tk<-302400
%     tk=tk+604800;
% else
%     tk=tk+0;
% end
Mk=M0+n*tk;
%3.计算偏近点角（迭代）
%E=M+e*sin(E);
ed(1)=Mk;
for i=1:4
   ed(i+1)=Mk+e*sin(ed(i));
end
Ek=ed(5);
%4.计算真近点角
Vk=atan2(sqrt(1-e.^2)*sin(Ek),(cos(Ek)-e));
%5.计算升交距角（未经改进时）
u=omega+Vk;
%6.计算摄动改正项
deltau=Cuc*cos(2*u)+Cus*sin(2*u);
deltar=Crc*cos(2*u)+Crs*sin(2*u);
deltai=Cic*cos(2*u)+Cis*sin(2*u);
%7.计算摄动改正后的升交距角uk、卫星矢径rk和轨道倾角ik
uk=u+deltau;
rk=(sqrtA.^2)*(1-e*cos(Ek))+deltar;
ik=i0+deltai+iDOT*tk;
%8.计算卫星在轨道面坐标系中的坐标
x=rk*cos(uk);
y=rk*sin(uk);
%9.计算发射时刻升交点的经度67
L=OMEGA+(OMEGA_DOT-7.29211567E-5)*tk-7.292115E-5*toe;
%10.计算卫星在地固坐标系下坐标
Xs=x*cos(L)-y*cos(ik)*sin(L);
Ys=x*sin(L)+y*cos(ik)*cos(L);
Zs=y*sin(ik);
            
        end


        function [toc,a0,a1,a2,Crs,Delta_n,M0,Cuc,e,Cus,sqrtA,toe,Cic,OMEGA,Cis,i0,Crc,omega,OMEGA_DOT,iDOT]=readata(app,file,PRNwanted,tob)
%file        读入的文件需去除头文件部分，获得卫星号顶行的数据
%PRNwanted   想要读取数据的卫星号PRN
%tob         观测时刻的年月日时分秒，年取后两位
%t           观测时刻对应的GPST，可缺省
data=importdata(file);
j=1;
for i=1:8:length(data(:,:))
    temp=data(i:i+7,:);
    if str2double(temp{1,1}(1:2))==PRNwanted
        finded(j,:)=temp';
        j=j+1;
    end
end
findedNum=length(finded(:,1));
%取整小时观测时刻UTC，用于寻找观测时刻当天合适的卫星参考时刻
if mod(tob(4),2)~=0
    if tob(5)>0
        th=tob(4)+1;
    else
    th=tob(4)-mod(tob(4),2);
    end
else
    th=tob(4);
end
if  th==24
    th=0;
    th1=24;
    for i=1:findedNum
       jde(i)=(str2double(finded{i,1}(10:11))==(tob(3)+1)&&str2double(finded{i,1}(13:14))==th) || str2double(finded{i,1}(13:14))+1==th1;
    end
else
    th1=th;
    for i=1:findedNum
       jde(i)=str2double(finded{i,1}(13:14))==th || str2double(finded{i,1}(13:14))+1==th1;
    end
end
tmp=[];
for i=1:findedNum
    if jde(i)
        tmp=finded(i,:)';
        %将要计算的卫星数据写入data_select.txt，用户可自行查看
        fileID = fopen('n_selected.txt','w');
        formatSpec = '%s \n';
        [nrows,~] = size(tmp);
        for row = 1:nrows
            fprintf(fileID,formatSpec,tmp{row,:}); % 注意此处不用{}，而是（）时，得到的字符串加引号，需注意
        end
        fclose(fileID);
        break
    end
end
if isempty(tmp)
    disp('n文件中无匹配数据')
    close;
end
clear data th i j tmp finded thf formatSpec nrows row fileID;
%将有关参数读入工作区
[data1,data2,data3,data4]=textread('n_selected.txt','%f%f%f%f','headerlines', 1);
Cuc=data1(2);toe=data1(3);i0=data1(4);iDOT=data1(5);
Crs=data2(1);e=data2(2);Cic=data2(3);Crc=data2(4);
Delta_n=data3(1);Cus=data3(2);OMEGA=data3(3);omega=data3(4);
M0=data4(1);sqrtA=data4(2);Cis=data4(3);OMEGA_DOT=data4(4);
data=importdata('n_selected.txt');
data=data{1,1};
PRN=data(1:2);year=str2double(data(4:5));month=str2double(data(7:8));day=str2double(data(10:11));
hour=str2double(data(13:14));mintue=str2double(data(16:17));second=str2double(data(19:22));
a0=eval(data(23:41));a1=eval(data(42:60));a2=eval(data(61:79));
clear data data1 data2 data3 data4 ;
[~, toc]=UTC2GPST(app,year,month,day,hour,mintue,second);
            
        end
        
        
        function [GPSweek ,GPSsec]=UTC2GPST(app,y,m,d,h,min,s)

if y<90
    y=y+2000;
else
    y=y+1900;
end

if m<=2
    y=y-1;m=m+12;
end
MJD=fix(365.25*y)+fix(30.6001*(m+1))+d+(h+(min+s/60)/60)/24+1720981.5-2400000.5;
GPSweek=fix((MJD-44244)/7);
GPSsec=fix(mod((MJD-44244),7))*86400+h*3600+min*60+s;
            
        end
        
    end


    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            PRNwanted=app.PRNEditField.Value;
            tob(1)=app.yEditField.Value;
            tob(2)=app.mEditField.Value;
            tob(3)=app.dEditField.Value;
            tob(4)=app.hEditField.Value;
            tob(5)=app.mEditField_2.Value;
            tob(6)=app.sEditField.Value;
            [~,t]=UTC2GPST(app,tob(1),tob(2),tob(3),tob(4),tob(5),tob(6));
            te(1)=app.yEditField_2.Value;
            te(2)=app.mEditField_4.Value;
            te(3)=app.dEditField_2.Value;
            te(4)=app.hEditField_2.Value;
            te(5)=app.mEditField_3.Value;
            te(6)=app.sEditField_2.Value;
            file=app.pathEditField.Value;
            if sum(te)==0
                [toc,a0,a1,a2,Crs,Delta_n,M0,Cuc,e,Cus,sqrtA,toe,Cic,OMEGA,Cis,i0,Crc,omega,OMEGA_DOT,iDOT]=readata(app,file,PRNwanted,tob);
            else
                [toc,a0,a1,a2,Crs,Delta_n,M0,Cuc,e,Cus,sqrtA,toe,Cic,OMEGA,Cis,i0,Crc,omega,OMEGA_DOT,iDOT]=readata(app,file,PRNwanted,te);
            end
            [Xs,Ys,Zs] = comsatpos(app,t,toc,a0,a1,a2,Crs,Delta_n,M0,Cuc,e,Cus,sqrtA,toe,Cic,OMEGA,Cis,i0,Crc,omega,OMEGA_DOT,iDOT);
            app.XsEditField.Value=Xs;
            app.YsEditField.Value=Ys;
            app.ZsEditField.Value=Zs;
        end

        % Value changed function: dEditField
        function dEditFieldValueChanged(app, event)
            value = app.dEditField.Value;
            %dayo=value ;
        end

        % Value changed function: dEditField_2
        function dEditField_2ValueChanged(app, event)
            value = app.dEditField_2.Value;
              %          daye=value;
        end

        % Value changed function: hEditField
        function hEditFieldValueChanged(app, event)
            value = app.hEditField.Value;
            %houro=value;
        end

        % Value changed function: hEditField_2
        function hEditField_2ValueChanged(app, event)
            value = app.hEditField_2.Value;
               %         houre=value;
        end

        % Value changed function: mEditField
        function mEditFieldValueChanged(app, event)
            value = app.mEditField.Value;
            %montho=value ;
        end

        % Value changed function: mEditField_2
        function mEditField_2ValueChanged(app, event)
            value = app.mEditField_2.Value;
            %            minuteo=value;
        end

        % Value changed function: mEditField_3
        function mEditField_3ValueChanged(app, event)
            value = app.mEditField_3.Value;
    %        minutee=value;
        end

        % Value changed function: mEditField_4
        function mEditField_4ValueChanged(app, event)
            value = app.mEditField_4.Value;
             %           monthe=value;
        end

        % Value changed function: pathEditField
        function pathEditFieldValueChanged(app, event)
            value = app.pathEditField.Value;
      %      filen=value;
        end

        % Value changed function: sEditField
        function sEditFieldValueChanged(app, event)
            value = app.sEditField.Value;
             %           secondo=value;
        end

        % Value changed function: sEditField_2
        function sEditField_2ValueChanged(app, event)
            value = app.sEditField_2.Value;
     %       seconde=value;
        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)
            value = app.yEditField.Value;
            %yearo=value;
        end

        % Value changed function: yEditField_2
        function yEditField_2ValueChanged(app, event)
            value = app.yEditField_2.Value;
            %            yeare=value;
        end

        % Value changed function: XsEditField
        function XsEditFieldValueChanged(app, event)
            value = app.XsEditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.v100UIFigure = uifigure;
            app.v100UIFigure.Name = '卫星坐标计算v1.00';
            app.yEditFieldLabel = uilabel(app.v100UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.v100UIFigure, 'numeric');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.yEditField.Limits = [0 99];
            app.yEditField.ValueDisplayFormat = '%.0f';
            app.yEditField.Value = 1;
            app.mEditFieldLabel = uilabel(app.v100UIFigure);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.v100UIFigure, 'numeric');
            app.mEditField.ValueChangedFcn = createCallbackFcn(app, @mEditFieldValueChanged, true);
            app.mEditField.Limits = [0 12];
            app.mEditField.ValueDisplayFormat = '%.0f';
            app.mEditField.Value = 9;
            app.dEditFieldLabel = uilabel(app.v100UIFigure);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.v100UIFigure, 'numeric');
            app.dEditField.ValueChangedFcn = createCallbackFcn(app, @dEditFieldValueChanged, true);
            app.dEditField.Limits = [0 31];
            app.dEditField.ValueDisplayFormat = '%.0f';
            app.dEditField.Value = 4;
            app.hEditFieldLabel = uilabel(app.v100UIFigure);
            app.hEditFieldLabel.Text = 'h';
            app.hEditField = uieditfield(app.v100UIFigure, 'numeric');
            app.hEditField.ValueChangedFcn = createCallbackFcn(app, @hEditFieldValueChanged, true);
            app.hEditField.Limits = [0 24];
            app.hEditField.ValueDisplayFormat = '%.0f';
            app.hEditField.Value = 4;
            app.mEditField_2Label = uilabel(app.v100UIFigure);
            app.mEditField_2Label.Text = 'm';
            app.mEditField_2 = uieditfield(app.v100UIFigure, 'numeric');
            app.mEditField_2.ValueChangedFcn = createCallbackFcn(app, @mEditField_2ValueChanged, true);
            app.mEditField_2.Limits = [0 59];
            app.mEditField_2.ValueDisplayFormat = '%.0f';
            app.sEditFieldLabel = uilabel(app.v100UIFigure);
            app.sEditFieldLabel.Text = 's';
            app.sEditField = uieditfield(app.v100UIFigure, 'numeric');
            app.sEditField.ValueChangedFcn = createCallbackFcn(app, @sEditFieldValueChanged, true);
            app.sEditField.Limits = [0 59];
            app.sEditField.ValueDisplayFormat = '%.0f';
            app.yEditField_2Label = uilabel(app.v100UIFigure);
            app.yEditField_2Label.Text = 'y';
            app.yEditField_2 = uieditfield(app.v100UIFigure, 'numeric');
            app.yEditField_2.ValueChangedFcn = createCallbackFcn(app, @yEditField_2ValueChanged, true);
            app.yEditField_2.Limits = [0 99];
            app.yEditField_2.ValueDisplayFormat = '%.0f';
            app.mEditField_4Label = uilabel(app.v100UIFigure);
            app.mEditField_4Label.Text = 'm';
            app.mEditField_4 = uieditfield(app.v100UIFigure, 'numeric');
            app.mEditField_4.ValueChangedFcn = createCallbackFcn(app, @mEditField_4ValueChanged, true);
            app.mEditField_4.Limits = [0 12];
            app.mEditField_4.ValueDisplayFormat = '%.0f';
            app.dEditField_2Label = uilabel(app.v100UIFigure);
            app.dEditField_2Label.Text = 'd';
            app.dEditField_2 = uieditfield(app.v100UIFigure, 'numeric');
            app.dEditField_2.ValueChangedFcn = createCallbackFcn(app, @dEditField_2ValueChanged, true);
            app.dEditField_2.Limits = [0 31];
            app.dEditField_2.ValueDisplayFormat = '%.0f';
            app.hEditField_2Label = uilabel(app.v100UIFigure);
            app.hEditField_2Label.Text = 'h';
            app.hEditField_2 = uieditfield(app.v100UIFigure, 'numeric');
            app.hEditField_2.ValueChangedFcn = createCallbackFcn(app, @hEditField_2ValueChanged, true);
            app.hEditField_2.Limits = [0 24];
            app.hEditField_2.ValueDisplayFormat = '%.0f';
            app.mEditField_3Label = uilabel(app.v100UIFigure);
            app.mEditField_3Label.Text = 'm';
            app.mEditField_3 = uieditfield(app.v100UIFigure, 'numeric');
            app.mEditField_3.ValueChangedFcn = createCallbackFcn(app, @mEditField_3ValueChanged, true);
            app.mEditField_3.Limits = [0 59];
            app.mEditField_3.ValueDisplayFormat = '%.0f';
            app.sEditField_2Label = uilabel(app.v100UIFigure);
            app.sEditField_2Label.Text = 's';
            app.sEditField_2 = uieditfield(app.v100UIFigure, 'numeric');
            app.sEditField_2.ValueChangedFcn = createCallbackFcn(app, @sEditField_2ValueChanged, true);
            app.sEditField_2.Limits = [0 59];
            app.sEditField_2.ValueDisplayFormat = '%.0f';
            app.ObservationTimeLabel = uilabel(app.v100UIFigure);
            app.ObservationTimeLabel.Text = '观测时刻(Observation Time)';
            app.EphemerisTimeLabel = uilabel(app.v100UIFigure);
            app.EphemerisTimeLabel.Text = '星历时刻(Ephemeris Time)';
            app.pathLabel = uilabel(app.v100UIFigure);
            app.pathLabel.Text = '星历文件路径(path)';
            app.pathEditField = uieditfield(app.v100UIFigure, 'text');
            app.pathEditField.ValueChangedFcn = createCallbackFcn(app, @pathEditFieldValueChanged, true);
            app.pathEditField.Value = 'D:\document\MATLAB_out\GNSS课程\由n文件计算卫星位置\程序包\brdc2470_clearhdr.01n';
            app.OutputLabel = uilabel(app.v100UIFigure);
            app.OutputLabel.Text = '计算结果(Output)';
            app.XsEditFieldLabel = uilabel(app.v100UIFigure);
            app.XsEditFieldLabel.Text = 'Xs';
            app.XsEditField = uieditfield(app.v100UIFigure, 'numeric');
            app.XsEditField.ValueChangedFcn = createCallbackFcn(app, @XsEditFieldValueChanged, true);
            app.XsEditField.ValueDisplayFormat = '%10.3f';
            app.XsEditField.Editable = 'off';
            app.ZsEditFieldLabel = uilabel(app.v100UIFigure);
            app.ZsEditFieldLabel.Text = 'Zs';
            app.ZsEditField = uieditfield(app.v100UIFigure, 'numeric');
            app.ZsEditField.ValueDisplayFormat = '%10.3f';
            app.ZsEditField.Editable = 'off';
            app.YsEditFieldLabel = uilabel(app.v100UIFigure);
            app.YsEditFieldLabel.Text = 'Ys';
            app.YsEditField = uieditfield(app.v100UIFigure, 'numeric');
            app.YsEditField.ValueDisplayFormat = '%10.3f';
            app.YsEditField.Editable = 'off';
            app.NotesTextAreaLabel = uilabel(app.v100UIFigure);
            app.NotesTextAreaLabel.Text = '说明(Notes)：';
            app.NotesTextArea = uitextarea(app.v100UIFigure);
            app.NotesTextArea.Editable = 'off';
            app.NotesTextArea.Value = {'1.输入的导航电文应去除头文件部分。'; '1.The header part of the incoming Navigation Message must be removed.'; '2.星历时刻都为0时，默认就近取与观测时刻相差不到一小时的时刻作为星历时刻。'; '2. When the Ephemeris Time is all 0, the nearest time within one hour''s difference from the Observed Time is taken as the Ephemeris Time by default.'};
            app.StartButton = uibutton(app.v100UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PRNEditFieldLabel = uilabel(app.v100UIFigure);
            app.PRNEditFieldLabel.Text = 'PRN';
            app.PRNEditField = uieditfield(app.v100UIFigure, 'numeric');
            app.PRNEditField.Limits = [0 40];
            app.PRNEditField.ValueDisplayFormat = '%.0f';
            app.PRNEditField.Value = 5;
            app.unitmLabel = uilabel(app.v100UIFigure);
            app.unitmLabel.Text = 'unit : m';
            app.TextArea = uitextarea(app.v100UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'contct : https://github.com/pycppmath/-calculate-the-satellite-s-position-by-the-navigation-message.git'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Calculating_Satellite_Position

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.v100UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.v100UIFigure)
        end
    end
end